classdef SOMAS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SOMASUIFigure           matlab.ui.Figure
        SelectoneEDFfileButton  matlab.ui.control.Button
        Image                   matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.SOMASUIFigure,"center")
        end

        % Button pushed function: SelectoneEDFfileButton
        function SelectoneEDFfileButtonPushed(app, event)
            d = [datestr(now,'yyyymmddTHHMMSS') '_logFile.txt'];
            diary(d)
            app.SOMASUIFigure.WindowState = 'minimize';
            SOMAS_main;
            
        end

        % Callback function
        function SelectadirectorywithmoreEDFfilesButtonPushed(app, event)
            diary logFile.txt
            EMGcalculator_morefiles
        end

        % Callback function
        function SleepEMGanalysissoftwareUIFigureButtonDown(app, event)
            movegui(app.SOMASUIFigure,"center")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SOMASUIFigure = uifigure('Visible', 'off');
            app.SOMASUIFigure.Name = 'SOMAS';
            app.SOMASUIFigure.Icon = 'Icon.jpg';
            app.Image = uiimage(app.SOMASUIFigure);
            app.Image.ImageSource = 'Logo_v3.jpg';
            app.SelectoneEDFfileButton = uibutton(app.SOMASUIFigure, 'push');
            app.SelectoneEDFfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoneEDFfileButtonPushed, true);
            app.SelectoneEDFfileButton.Text = 'Select one EDF+ file';
            % Show the figure after all components are created
            app.SOMASUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SOMAS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SOMASUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SOMASUIFigure)
        end
    end
end